classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        TabGroup    matlab.ui.container.TabGroup
        Tab         matlab.ui.container.Tab
        Button_8    matlab.ui.control.Button
        DropDown_2  matlab.ui.control.DropDown
        Label_8     matlab.ui.control.Label
        Button_6    matlab.ui.control.Button
        TextArea_2  matlab.ui.control.TextArea
        Label_7     matlab.ui.control.Label
        Image2      matlab.ui.control.Image
        TextArea    matlab.ui.control.TextArea
        Label_5     matlab.ui.control.Label
        Button_4    matlab.ui.control.Button
        Image_5     matlab.ui.control.Image
        Lamp        matlab.ui.control.Lamp
        LampLabel   matlab.ui.control.Label
        Button      matlab.ui.control.Button
        DropDown    matlab.ui.control.DropDown
    end


    properties (Access = private)% 添加了一个私有函数
        COMS
        port%串口号
        port_use%搜索到的串口号
        s%选中/正在使用的串口
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [app.port] = get_com_port();
            app.DropDown.Items=app.port;
            app.port_use=app.port(1);
            app.s=serial(app.port_use,'BaudRate',9600.00,'Terminator','LF/CR');

        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            for i=1:length(app.DropDown.Items)
                if strcmp(value,app.DropDown.Items{i})
                    app.port_use=app.port(i);
                    app.s=serial(app.port_use,'BaudRate',9600.00,'Terminator','LF/CR');
                end
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            [app.port] = get_com_port();
            app.DropDown.Items=app.port;
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            set(app.s,'Timeout',0.1);%设置串口延时为0.1秒
            if strcmp(get(app.s,'Status'),'closed')%判断串口状态，若关闭
                fopen(app.s);%则打开该串口
                if strcmp(get(app.s,'Status'),'open')%判断是否打开串口成功
                    app.Lamp.Color='g';
                    app.Button_6.Text='关闭串口';%成功打开串口，则指示灯变绿，并更改文本为"关闭串口"
                end
            else
                fclose(app.s);
                if strcmp(get(app.s,'Status'),'closed')
                    app.Lamp.Color='r';
                    app.Button_6.Text='打开串口';
                end
            end
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            %绘制抬起角度的仿真波形图
            figure();
%             x1=evalin('base','out.data1_1.time');%evalin函数从工作区中获取数据
%             y1=evalin('base','out.data1_1.signals(1).values');
%             plot(x1,y1,Color='r');
%             hold on;
            x2=evalin('base','out.data1_2.time');
            y2=evalin('base','out.data1_2.signals.values');
            plot(x2,y2,Color='b');
            xlabel('时间');
            ylabel('角度');
            title('俯仰轴仿真波形');
            %绘制平转角度的仿真波形图
            figure();
%             x3=evalin('base','out.data2_1.time');%evalin函数从工作区中获取数据
%             y3=evalin('base','out.data2_1.signals(2).values');
%             plot(x3,y3,Color='r');
            hold on;
            x4=evalin('base','out.data2_2.time');
            y4=evalin('base','out.data2_2.signals.values');
            plot(x4,y4,Color='b');
            xlabel('时间');
            ylabel('角度');
            title('方位轴仿真波形');


%             figure();   %新建绘制窗口
%             plot(out.U.time,out.U.signals(2).values,'LineWidth',1);
%             xlabel('时间');
%             ylabel('幅值');

            % subplot(3,1,2);
            % plot(Uabc.time,Uabc.signals(2).values(:,1),'LineWidth',1);
            % ylabel('幅值');


%             figure;
%             hplot=plot(NaN,NaN);% 创建一个空的plot对象,这个图像是反应"抬起角度"的
%             %还需要另外一个图像，用来反应"平转角度"
%             grid on;
%             ylim([0,100]); % 设置y轴坐标轴范围，根据实际情况调整
%             xlabel('时间');
%             ylabel('幅度');
%             %通过for循环实现实时更新x坐标
%             for i = 1:2500
%                 data = fread(app.s,1,'uint32');%fread读取二进制数据，从simulink接收uint32型数据，每次接收1个
%                 %data=double(data);
%                 %disp(data);          
%                 %更新绘图数据
%                 xData = get(hplot, 'XData');
%                 yData = get(hplot, 'YData');
%                 xData = [xData, i]; %更新X轴坐标
%                 yData = [yData, data]; %更新Y轴坐标
%                 set(hplot, 'XData', xData, 'YData', yData);
% 
%                 drawnow; % 刷新绘图窗口
%                 pause(0.2); % 控制绘图速度，根据实际情况调整

        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            %从虚拟串口发送数据到simulink，需要发送两个数据
            %需要设置定时发送，这里用for循环实现
            
            
            for i=1:2500
                if strcmp(get(app.s,'Status'),'open')%判断串口状态，若处于开启状态

                    %data1=str2double(app.TextArea.Value);%将输入的"抬起角度"数据转化为double型
                    %data1=single(data1);%将数据转化为float型
                    %data1=sprintf('%.2f',data1);% 将数据格式化为字符串，保留两位小数

                    %disp(num2str(data1));
                    %data1=data1*100;%将输入的数，保留小数点后两位
                    %disp(num2str(data1));
                    %data1=num2str(data1);%将data1转化为字符串
                    %data1=uint8(data1);
                    %disp(num2str(data1));
                    %data1=num2str(data1);%再将data1转化为字符串
                    %disp(num2str(data1));%带小数的也可以直接转成字符串,例如30.55
                    %data1=sprintf('%06s',data1);%data1前补0
                    %disp(data1);%命令行窗口展示的结果为"03055"，说明没有问题
                    %data1=strcat('0',data1);%在data1前添加包头'0'
                    %data1=double(data1);%将data1转换为uint16型数据！！！！！！！！！！
                    %disp(num2str(data1));

                    %data2=str2double(app.TextArea_2.Value);%将输入的"平转角度"数据转化为double型
                    %data2=single(data2);
                    %data2=sprintf('%.2f',data2);
                    %data2=data2*100;%将输入的数，保留小数点后两位
                    %data2=int16(data2);%将data2转化为int型
                    %data2=num2str(data2);%再将data2转化为字符串
                    %data2=sprintf('%06s',data2);%data2前补0
                    %data2=strcat('0',data2);
                    %data2=double(data2);%将data2转换为uint16型数据！！！！！！！！！！

                    %data=strcat(data1,data2);%将两个字符串拼接
                    %data=str2num(data);%将拼接后的字符串转化为数值

                    %data=[data1,data2];%将两个数据转换为uint8数组
                    %disp(data);
                    %将数值转换成uint8型，与serial receive模块的data type对应
                    %databytes=typecast(data,'uint8');%将double型数据转换成字节流
                    %data=[data1 ' ' data2];%将data1和data2拼接，中间用空格
                    %data=uint8(data);
                    %fwrite(app.s,data);
                    %用fwrite函数测试一下发送uint8型数据,fread写入二进制数据

                    data1=str2double(app.TextArea.Value);%将输入的"抬起角度"数据转化为double型
                    data1=data1*100;%不需要那么多乱七八糟的格式转换，直接乘以100，保留两位小数，默认将data转换为整型，然后直接发送就行
                    
                    data2=str2double(app.TextArea_2.Value);
                    data2=data2*100;
                    
                    data1=num2str(data1);
                    data2=num2str(data2);
                    data2_length=strlength(data2);
                    data2_length=[0,data2_length];
                    assignin('base','data2_length',data2_length);%将data2字符串长度写入工作区

                    data=strcat(data1,data2);%将字符串data1和data2拼接
                    data=str2num(data);
                    
                    fwrite(app.s,data,'uint32');%fprintf写文字，fwrite写数据

                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '上位器';
            app.DropDown = uidropdown(app.Tab);
            app.DropDown.Items = {'No.1', 'No.2'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'No.1';
            app.Button = uibutton(app.Tab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '搜索串口：';
            app.LampLabel = uilabel(app.Tab);
            app.LampLabel.Text = '串口信号：';
            app.Lamp = uilamp(app.Tab);
            app.Image_5 = uiimage(app.Tab);
            app.Image_5.ImageSource = fullfile(pathToMLAPP, '抬起角度.png');
            app.Button_4 = uibutton(app.Tab, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '发送数据';
            app.Label_5 = uilabel(app.Tab);
            app.Label_5.Text = '输入抬起角度：';
            app.TextArea = uitextarea(app.Tab);
            app.Image2 = uiimage(app.Tab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, '平转角度.png');
            app.Label_7 = uilabel(app.Tab);
            app.Label_7.Text = '输入平转角度：';
            app.TextArea_2 = uitextarea(app.Tab);
            app.Button_6 = uibutton(app.Tab, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '打开串口';
            app.Label_8 = uilabel(app.Tab);
            app.Label_8.Text = '波特率：';
            app.DropDown_2 = uidropdown(app.Tab);
            app.DropDown_2.Items = {'0', '2400', '4800', '9600', '19200', '38400', '57600', '115200'};
            app.DropDown_2.Value = '9600';
            app.Button_8 = uibutton(app.Tab, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '开始绘制';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end